classdef frontendapp < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                 matlab.ui.Figure
        Panel                    matlab.ui.container.Panel
        DepartmentDropDownLabel  matlab.ui.control.Label
        DepartmentDropDown       matlab.ui.control.DropDown
        BakeryDropDownLabel      matlab.ui.control.Label
        BakeryDropDown           matlab.ui.control.DropDown
        DropDownLabel            matlab.ui.control.Label
        DropDown                 matlab.ui.control.DropDown
        SelectItemLabel          matlab.ui.control.Label
        DropDown2Label           matlab.ui.control.Label
        DropDown2                matlab.ui.control.DropDown
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Value changed function: DropDown2
        function DropDown2ValueChanged(app, event)
            value = app.DropDown2.Value;
            Bakery = {'French Loaf' ,'Donuts', 'Pastries' ,'Burger Buns'};
            Fruits_Vegetables = {'Apples' ,'Oranges' ,'Lettuce' ,'Carrot' ,'Eggplant'};
            val= get(value)
            if (value == 1)
                set(text,'String', Bakery);
            elseif (value == 2)
                 set(text,'String', Fruits_Vegetables);
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.Panel = uipanel(app.UIFigure);
            app.Panel.Title = 'Panel';
            app.DepartmentDropDownLabel = uilabel(app.Panel);
            app.DepartmentDropDownLabel.Text = 'Department';
            app.DepartmentDropDown = uidropdown(app.Panel);
            app.DepartmentDropDown.Items = {'Apples', 'Oranges', 'Lettuce', 'Carrot', 'Eggplant', '', ''};
            app.DepartmentDropDown.Visible = 'off';
            app.DepartmentDropDown.Value = 'Apples';
            app.BakeryDropDownLabel = uilabel(app.Panel);
            app.BakeryDropDownLabel.Visible = 'off';
            app.BakeryDropDownLabel.Text = 'Bakery';
            app.BakeryDropDown = uidropdown(app.Panel);
            app.BakeryDropDown.Visible = 'off';
            app.DropDownLabel = uilabel(app.Panel);
            app.DropDownLabel.Visible = 'off';
            app.DropDownLabel.Text = 'Drop Down';
            app.DropDown = uidropdown(app.Panel);
            app.DropDown.Items = {'Bakery', 'Fruits/Vegetables'};
            app.DropDown.Value = 'Bakery';
            app.SelectItemLabel = uilabel(app.Panel);
            app.SelectItemLabel.Text = 'Select Item';
            app.DropDown2Label = uilabel(app.Panel);
            app.DropDown2Label.Visible = 'off';
            app.DropDown2Label.Text = 'Drop Down2';
            app.DropDown2 = uidropdown(app.Panel);
            app.DropDown2.ValueChangedFcn = createCallbackFcn(app, @DropDown2ValueChanged, true);
            app.DropDown2.Tag = 'text';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = frontendapp

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end